classdef CircularInterferencePatternAnalyser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GenerateDataButton       matlab.ui.control.Button
        MaxRadiusEditField       matlab.ui.control.NumericEditField
        MaxRadiusEditFieldLabel  matlab.ui.control.Label
        AngleLimit2Slider        matlab.ui.control.Slider
        AngleLimit2SliderLabel   matlab.ui.control.Label
        AngleLimitSlider         matlab.ui.control.Slider
        AngleLimitSliderLabel    matlab.ui.control.Label
        AutoFindCenterButton     matlab.ui.control.Button
        CenterYEditField         matlab.ui.control.NumericEditField
        CenterYEditFieldLabel    matlab.ui.control.Label
        CenterXEditField         matlab.ui.control.NumericEditField
        CenterXEditFieldLabel    matlab.ui.control.Label
        LoadImageButton          matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imagePlot
        center % Plot for the + symbol where the centre should be
        centerPos % the X location of the centres
        IMAGE
        LIMITS % theta 1, theta 2 , Radius
        limitCircle1
        limitCircle2
        limitCircle3
        FileName
      
    end
    
    properties (Access = public)
        val = zeros(5000, 1); % Description
    end
    
    methods (Access = public)
        
        function DrawCenter(app)
            if app.FileName ~= 0
                delete(app.center)
                delete(app.limitCircle1)
                delete(app.limitCircle2)
                delete(app.limitCircle3)
                t = (pi/180)*linspace(app.LIMITS(1), app.LIMITS(2),200)
                R = linspace(0, app.LIMITS(3), 100);
                Circle =  app.LIMITS(3).*[cos(t); sin(t)] + app.centerPos';
                app.limitCircle1 = plot(Circle(1,:), Circle(2,:),'Color','green', 'Parent', app.UIAxes, 'LineWidth', 7);
                app.limitCircle2 = plot(R.*cos((pi/180)*app.LIMITS(1)) + app.centerPos(1), R.*sin((pi/180)*app.LIMITS(1)) + app.centerPos(2),'Color','green', 'Parent', app.UIAxes, 'LineWidth', 7);
                app.limitCircle3 = plot(R*cos((pi/180)*app.LIMITS(2))+ app.centerPos(1), R*sin((pi/180)*app.LIMITS(2))+ app.centerPos(2),'Color','green', 'Parent', app.UIAxes, 'LineWidth', 7);
                app.center = plot(app.centerPos(1), app.centerPos(2), 'o', 'Color','Blue', 'Parent', app.UIAxes, 'LineWidth', 7);
            end
        end
    end
    
    methods (Access = private)
        
        function UpdateSliders(app, xVal, yVal)
        app.CenterXEditField.Value = xVal;
        app.CenterYEditField.Value = yVal;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [app.FileName, path, index] = uigetfile('*.*', 'Select an Image File');
            if app.FileName ~= 0
                app.IMAGE = imread([path, app.FileName]);
                hold(app.UIAxes, 'off');
                delete(app.imagePlot)
                app.imagePlot = imshow(app.IMAGE, 'Parent', app.UIAxes);
                hold(app.UIAxes, 'on');
                [rows, columns, colours] = size(app.IMAGE);
                app.centerPos = [floor(columns/2),  floor(rows/2)];
                UpdateSliders(app, columns/2, rows/2);
                app.LIMITS = [app.AngleLimitSlider.Value, app.AngleLimit2Slider.Value, app.MaxRadiusEditField.Value];
                DrawCenter(app);
            end
        end

        % Value changed function: CenterXEditField
        function CenterXEditFieldValueChanged(app, event)
            value = app.CenterXEditField.Value;
            app.centerPos(1) = value;
            DrawCenter(app);
        end

        % Value changed function: CenterYEditField
        function CenterYEditFieldValueChanged(app, event)
            value = app.CenterYEditField.Value;
            app.centerPos(2) = value;
            DrawCenter(app);
        end

        % Button pushed function: AutoFindCenterButton
        function AutoFindCenterButtonPushed(app, event)
            Im2 = imresize(app.IMAGE, 0.1);
            [centers, radii, metric] = imfindcircles(Im2,[10, 400]);
            app.centerPos(1) = mean(centers(:,1))*10;
            app.centerPos(2) = mean(centers(:,2))*10;
            UpdateSliders(app, mean(centers(:,1))*10, mean(centers(:,2))*10);
            DrawCenter(app);
        end

        % Value changed function: AngleLimitSlider
        function AngleLimitSliderValueChanged(app, event)
            value = app.AngleLimitSlider.Value;
            app.LIMITS(1) = value;
            DrawCenter(app);
        end

        % Value changed function: AngleLimit2Slider
        function AngleLimit2SliderValueChanged(app, event)
            value = app.AngleLimit2Slider.Value;
            app.LIMITS(2) = value;
            DrawCenter(app);
        end

        % Callback function
        function MaxRadiusSliderValueChanged(app, event)
            value = app.MaxRadiusSlider.Value;
            app.LIMITS(3) = value;
            DrawCenter(app);
        end

        % Value changed function: MaxRadiusEditField
        function MaxRadiusEditFieldValueChanged(app, event)
            value = app.MaxRadiusEditField.Value;
            app.LIMITS(3) = value;
            DrawCenter(app);
        end

        % Button pushed function: GenerateDataButton
        function GenerateDataButtonPushed(app, event)
            % between the limits we've selected we want to generate a csv
            % that will list the average pixel intensity vs radius.
            app.val = zeros(5000, 1);
            R = linspace(1, app.LIMITS(3), app.LIMITS(3));
            t = linspace(app.LIMITS(1)*pi/180, app.LIMITS(2)*pi/180, 400);
            Img = rgb2gray(app.IMAGE);
            for i = 1:length(R)
                for j = 1:length(t)
                    app.val(i) = double(app.val(i) + double(Img( floor(R(i)*sin(t(j)) + app.centerPos(2)), floor(R(i).*cos(t(j)) + app.centerPos(1)) )));
                end
            end
            app.val = app.val/400;
            csvwrite([app.FileName, '.csv'], app.val(1:length(R)))
            hold(app.UIAxes2, "off");
            plot(app.val(1:length(R)),'Parent', app.UIAxes2, 'Color','blue');
            hold(app.UIAxes2, "on");
          %  [pks, locs] = findpeaks(app.val(300:length(R)),'MinPeakProminence',max(app.val)/30);
          %  plot(locs+299, pks, '*', 'Color','green','Parent', app.UIAxes2);
          %  for i = 1:length(locs)
          %      text(locs(i)+299,pks(i),[num2str(locs(i)+299)],'Parent', app.UIAxes2)
          %  end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Imported Image')
            xlabel(app.UIAxes, 'X (Px)')
            ylabel(app.UIAxes, 'Y (Px)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'IMAGEAXIS';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Intensity vs Radius')
            xlabel(app.UIAxes2, 'Radius from Centre (Pixels)')
            ylabel(app.UIAxes2, 'Average Pixel Intensity')
            zlabel(app.UIAxes2, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.CenterXEditFieldLabel = uilabel(app.UIFigure);
            app.CenterXEditFieldLabel.Text = 'Center - X';
            app.CenterXEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterXEditField.ValueChangedFcn = createCallbackFcn(app, @CenterXEditFieldValueChanged, true);
            app.CenterYEditFieldLabel = uilabel(app.UIFigure);
            app.CenterYEditFieldLabel.Text = {'Center - Y'; ''};
            app.CenterYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterYEditField.ValueChangedFcn = createCallbackFcn(app, @CenterYEditFieldValueChanged, true);
            app.AutoFindCenterButton = uibutton(app.UIFigure, 'push');
            app.AutoFindCenterButton.ButtonPushedFcn = createCallbackFcn(app, @AutoFindCenterButtonPushed, true);
            app.AutoFindCenterButton.Text = 'Auto Find Center';
            app.AngleLimitSliderLabel = uilabel(app.UIFigure);
            app.AngleLimitSliderLabel.Text = 'Angle Limit';
            app.AngleLimitSlider = uislider(app.UIFigure);
            app.AngleLimitSlider.Limits = [0 360];
            app.AngleLimitSlider.MajorTicks = [0 90 180 270 360];
            app.AngleLimitSlider.ValueChangedFcn = createCallbackFcn(app, @AngleLimitSliderValueChanged, true);
            app.AngleLimit2SliderLabel = uilabel(app.UIFigure);
            app.AngleLimit2SliderLabel.Text = 'Angle Limit 2';
            app.AngleLimit2Slider = uislider(app.UIFigure);
            app.AngleLimit2Slider.Limits = [0 360];
            app.AngleLimit2Slider.MajorTicks = [0 90 180 270 360];
            app.AngleLimit2Slider.ValueChangedFcn = createCallbackFcn(app, @AngleLimit2SliderValueChanged, true);
            app.AngleLimit2Slider.Value = 360;
            app.MaxRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.MaxRadiusEditFieldLabel.Text = 'Max Radius';
            app.MaxRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @MaxRadiusEditFieldValueChanged, true);
            app.MaxRadiusEditField.Value = 1400;
            app.GenerateDataButton = uibutton(app.UIFigure, 'push');
            app.GenerateDataButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDataButtonPushed, true);
            app.GenerateDataButton.Text = {'Generate Data'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CircularInterferencePatternAnalyser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end